classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        QuadraticFormulaLabel  matlab.ui.control.Label
        X2Label                matlab.ui.control.Label
        X1Label                matlab.ui.control.Label
        CalculateButton        matlab.ui.control.Button
        Value_cEditField       matlab.ui.control.NumericEditField
        Value_cEditFieldLabel  matlab.ui.control.Label
        Value_bEditField       matlab.ui.control.NumericEditField
        Value_bEditFieldLabel  matlab.ui.control.Label
        Value_aEditField       matlab.ui.control.NumericEditField
        Value_aEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            a = app.Value_aEditField.Value;
            b = app.Value_bEditField.Value;
            c = app.Value_cEditField.Value;
            d = ((b^2)-4*a*c);
      if d<0 
            fprintf('no solution found %.2f\n')
      else
          x1 = (-b+sqrt(d))/(2*a);
          x2 = (-b-sqrt(d))/(2*a);
      end
            app.X1Label.Text = string(x1);
            app.X2Label.Text = string(x2);
            x = linspace(x1,x2,100);
            y = a.*((x).^2)+b.*(x2)+c;

            plot(x,y)
            grid on
            title("quadratic Formula")
            xlabel("X")
            ylabel("y")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Value_aEditFieldLabel = uilabel(app.UIFigure);
            app.Value_aEditFieldLabel.Text = 'Value_a';
            app.Value_aEditField = uieditfield(app.UIFigure, 'numeric');
            app.Value_bEditFieldLabel = uilabel(app.UIFigure);
            app.Value_bEditFieldLabel.Text = 'Value_b';
            app.Value_bEditField = uieditfield(app.UIFigure, 'numeric');
            app.Value_cEditFieldLabel = uilabel(app.UIFigure);
            app.Value_cEditFieldLabel.Text = 'Value_c';
            app.Value_cEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.X1Label = uilabel(app.UIFigure);
            app.X1Label.Text = 'X1 :';
            app.X2Label = uilabel(app.UIFigure);
            app.X2Label.Text = 'X2 :';
            app.QuadraticFormulaLabel = uilabel(app.UIFigure);
            app.QuadraticFormulaLabel.Text = 'Quadratic Formula';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end