classdef app4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Label2                  matlab.ui.control.Label
        LoginButton             matlab.ui.control.Button
        PasswordEditField       matlab.ui.control.EditField
        PasswordEditFieldLabel  matlab.ui.control.Label
        LogincheckLabel         matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        UserNameEditField       matlab.ui.control.EditField
        UserNameEditFieldLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        counter = 0;  % this is the counter that counts tries of log in
         
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
           user = app.UserNameEditField.Value;
           pass = app.PasswordEditField.Value;
           correct_user_name = "Oren";
           correct_password = "123";
           if app.counter <= 3
                    if user == correct_user_name && pass == correct_password   
                       app.Label.Text = 'OK!';
                       app.LoginButton.Enable = "off";
               else
                    app.Label.Text = 'Incorrect, Try again';
                    app.counter = app.counter+1;
                    app.Label2.Text = string(app.counter);
                    end
           else
               app.Label.Visible = "off";
               app.UserNameEditField.Visible = "off";
               app.PasswordEditField.Visible = "off";


           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UserNameEditFieldLabel = uilabel(app.UIFigure);
            app.UserNameEditFieldLabel.Text = 'User Name';
            app.UserNameEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.LogincheckLabel = uilabel(app.UIFigure);
            app.LogincheckLabel.Text = 'Login check';
            app.PasswordEditFieldLabel = uilabel(app.UIFigure);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.UIFigure, 'text');
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Log in';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end